classdef BLAST_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InputProfileCSVFilepathLabel   matlab.ui.control.Label
        InputPath                      matlab.ui.control.EditField
        OutputProfileCSVFileLocationLabel  matlab.ui.control.Label
        OutputPath                     matlab.ui.control.EditField
        SmoothingFactorSliderLabel     matlab.ui.control.Label
        Smoothing                      matlab.ui.control.Slider
        Generate                       matlab.ui.control.Button
        AutoUpdateLabel                matlab.ui.control.Label
        Auto                           matlab.ui.control.Switch
        ProfileGenerationLabel         matlab.ui.control.Label
        CounterweightCalculationLabel  matlab.ui.control.Label
        kgsLabel                       matlab.ui.control.Label
        PayloadMassSpinnerLabel        matlab.ui.control.Label
        Mass                           matlab.ui.control.Spinner
        LoadNum                        matlab.ui.control.Label
        Config                         matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        AccelAx                        matlab.ui.control.UIAxes
        SpeedAx                        matlab.ui.control.UIAxes
        LJerkAx                        matlab.ui.control.UIAxes
        AJerkAx                        matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        AccelRMSE                      matlab.ui.control.Label
        ProfleRootMeanSquareErrorsLabel  matlab.ui.control.Label
        CFunctionCallsTBDLabel         matlab.ui.control.Label
        ClipAccelRMSE                  matlab.ui.control.Label
        SpeedRMSE                      matlab.ui.control.Label
        LinJerkRMSE                    matlab.ui.control.Label
        ClipLinJerkRMSE                matlab.ui.control.Label
        AngJerkRMSE                    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generate
        function generateProfile(app, event)
            % constants
            g = 9.81;               % (m/s^2)
            dt = 0.01;              % (s) timestep of output data
            r = 1.5;                % (m) boom length
            b = 0;                  % (m) pivot to payload CG
            S = 5;                  % smoothing factor multiplier
            % acceleration clipping
            max_accel = 15*g;       % (m/s^2) maximum acceleration allowed
            min_accel = 1.005*g;	% (m/s^2) minimum acceleration allowed
            % braking
            brake_trig = -0.02;     % (rad/s^2) slope to trigger brake
            brake_on = 0.1;         % (s) time delay to apply brake
            brake_off = 0.02;       % (s) time delay to release brake
            

            %% User Input
            tau = S*app.Smoothing.Value; % (s) filter time constant
            % import file
            file = app.InputPath.Value;
            Data = importdata(file);
            t = Data(:,1);
            ax_raw = Data(:,2);
            
            %% Interpolate Data
            % preallocate vectors
            dt_old = t(2) - t(1);
            t_diff = dt_old/dt;
            t_temp = t(1):dt:t(end);
            a_temp = zeros(1,length(t_temp));
            % interpolation scheme
            for k = 1:(length(t)-1)
                t_0 = t(k); t_f = t(k+1);
                a_0 = ax_raw(k); a_f = ax_raw(k+1);
                m = (a_f-a_0)/(t_f-t_0);
                b = a_0 - m*t_0;
                for jj = 1:t_diff
                    a_temp((k-1)*t_diff + jj) =  m*t_temp((k-1)*t_diff + jj) + b;
                end
            end
            a_temp(end) = ax_raw(end);
            % overwrite old vectors
            t = t_temp;
            ax_raw = a_temp;
            
            %% Clip Acceleration Data
            % new clipped vector
            ax_clip = ax_raw;
            % apply lower acceleration limit
            mins = find(ax_clip < min_accel);
            for k = 1:length(mins)
                ax_clip(mins(k)) = min_accel;
            end
            % apply upper acceleration limit
            maxs = find(ax_clip < min_accel);
            for k = 1:length(maxs)
                ax_clip(maxs(k)) = max_accel;
            end
            
            %% Smooth Acceleration
            % limit cutoff frequency via Nyquist criterion
            sample_rate = 1/dt;
            fc_max = 0.5*sample_rate;
            fc = 1/tau;
            if fc >= fc_max
                fc = 0.99*fc_max;
            end
            % low pass fisrt order butterworth filter
            d = designfilt('lowpassiir', ...
                           'DesignMethod', 'butter', ...
                           'FilterOrder', 1, ...
                           'HalfPowerFrequency', fc, ...
                           'SampleRate', sample_rate);
            % forwards-backwards filter data
            ax_proc = filtfilt(d, ax_clip);
            
            %% Calculate Raw (Clipped) Velocity
            omega_raw = [];
            for k = 1:length(ax_clip)
                omega_raw(k) = sqrt((ax_clip(k)^2 - g^2)/(r + b*sqrt(1 - (g/ax_clip(k))^2)));
            end
            
            %% Calculate Processed Velocity
            omega_proc = [];
            for k = 1:length(ax_proc)
                omega_proc(k) = sqrt((ax_proc(k)^2 - g^2)/(r + b*sqrt(1 - (g/ax_proc(k))^2)));
            end
            
            %% Find Braking Points
            % determine everywhere brake should be applied
            brakes = zeros(1,length(t));
            for k = 1:(length(t)-1)
                if (omega_proc(k+1)-omega_proc(k))/(t(k+1)/t(k)) < brake_trig
                    brakes(k) = 1;
                end
            end
            
            %% Application and Release Delays
            % determine start and end points of braking section
            starts = [];
            ends = [];
            status = 0;
            for k = 1:(length(brakes)-1)
                if (brakes(k+1) == 1) && (status == 0)
                    starts = [starts k];
                    status = 1;
                elseif (brakes(k+1) == 0) && (status == 1)
                    ends = [ends k];
                    status = 0;
                end
            end
            % implement brake delays
            for k = 1:length(starts)
                starts(k) = starts(k) - round(brake_on/dt);
                ends(k) = ends(k) - round(brake_off/dt);
            end
            % combine overlapping brake periods 
            for k = 1:(length(starts)-1)
                if ends(k) >= starts(k+1)
                    ends(k) = 0;
                    starts(k+1) = 0;
                end
            end
            starts(starts == 0) = [];
            ends(ends == 0) = [];
            % finalize brake flags vector
            brakes = zeros(1,length(t));
            for k = 1:length(starts)
                brakes(starts(k):ends(k)) = 1;
            end
            
            %% Jerk Calculations 
            j_lin_raw = firstDeriv(ax_raw, t);  % raw linear jerk
            j_lin_clip = firstDeriv(ax_clip, t);    % clipped linear jerk
            j_lin_proc = firstDeriv(ax_proc, t);    % processed linear jerk
            j_ang_raw = secondDeriv(omega_raw, t);  % raw angular jerk
            j_ang_proc = secondDeriv(omega_proc, t);    % processed angular jerk
            
            %% Original and Processed Linear Acceleration
            hold(app.AccelAx, 'off')
            plot(app.AccelAx, t, ax_raw/g, "k", "linewidth", 2)
            hold(app.AccelAx, 'on')
            plot(app.AccelAx, t, ax_proc/g, "r", "linewidth", 2)
            plot(app.AccelAx, t(brakes == 1), ax_proc(brakes == 1)/g, ".b", "markersize", 10) 
            title(app.AccelAx, "Linear Acceleration")
            xlabel(app.AccelAx, "Time (s)")
            ylabel(app.AccelAx, "Acceleration (g)")
            legend(app.AccelAx, "Original", "Processed", "Braking Section", "location", "north")
            grid(app.AccelAx, 'on')
            
            %% Original and Processed Angular Speed
            hold(app.SpeedAx, 'off')            
            plot(app.SpeedAx, t, omega_raw, "k", "linewidth", 2)
            hold(app.SpeedAx, 'on')
            plot(app.SpeedAx, t, omega_proc, "r", "linewidth", 2)
            plot(app.SpeedAx, t(brakes == 1), omega_proc(brakes == 1), ".b", "markersize", 10) 
            title(app.SpeedAx, "Angular Speed")
            xlabel(app.SpeedAx, "Time (s)")
            ylabel(app.SpeedAx, "\omega (rad/s)")
            legend(app.SpeedAx, "Original", "Processed", "Braking Section", "location", "north")
            grid(app.SpeedAx, 'on')
            
            %% Original and Processed Linear Jerk
            hold(app.LJerkAx, 'off')  
            plot(app.LJerkAx, t, j_lin_raw, "k", "linewidth", 2)
            hold(app.LJerkAx, 'on')  
            plot(app.LJerkAx, t, j_lin_proc, "r", "linewidth", 2) 
            title(app.LJerkAx, "Linear Jerk")
            xlabel(app.LJerkAx, "Time (s)")
            ylabel(app.LJerkAx, "Jerk (m/s^3)")
            legend(app.LJerkAx, "Original", "Processed", "location", "north")
            grid(app.LJerkAx, 'on')  
            
            %% Original and Processed Angular Jerk
            hold(app.AJerkAx, 'off')  
            plot(app.AJerkAx, t, j_ang_raw, "k", "linewidth", 2)
            hold(app.AJerkAx, 'on')  
            plot(app.AJerkAx, t, j_ang_proc, "r", "linewidth", 2) 
            title(app.AJerkAx, "Angular Jerk")
            xlabel(app.AJerkAx, "Time (s)")
            ylabel(app.AJerkAx, "Jerk (rad/s^3)")
            legend(app.AJerkAx, "Original", "Processed", "location", "north")
            grid(app.AJerkAx, 'on')  
            
            %% Acceleration RMSEs
            ax_raw_rmse = rmse(ax_raw, ax_proc);    % raw RMSE
            app.AccelRMSE.Text = sprintf('Linear Accel RMSE = %1.4f', ax_raw_rmse);
            ax_clip_rmse = rmse(ax_clip, ax_proc);  % clipped RMSE
            app.ClipAccelRMSE.Text = sprintf('Clipped Lin. Acc. RMSE = %1.4f', ax_clip_rmse);
            
            %% Angular Speed RMSE
            omega_rmse = rmse(omega_raw, omega_proc);   % angular rmse
            app.SpeedRMSE.Text = sprintf('Angular Speed RMSE = %1.4f', omega_rmse);
            
            %% Jerk RMSEs
            j_lin_raw_rmse = rmse(j_lin_raw, j_lin_proc);   % raw linear RMSE
            app.LinJerkRMSE.Text = sprintf('Linear Jerk RMSE = %1.4f', j_lin_raw_rmse);
            j_lin_clip_rmse = rmse(j_lin_clip, j_lin_proc); % clipped linear RMSE
            app.ClipLinJerkRMSE.Text = sprintf('Clipped Lin. Jerk RMSE = %1.4f', j_lin_clip_rmse);
            j_ang_rmse = rmse(j_ang_raw, j_ang_proc);   % angular RMSE
            app.AngJerkRMSE.Text = sprintf('Angular Jerk RMSE = %1.4f', j_ang_rmse);
            
            %% Export Data
            % data format = [time (s), speed (rad/s), brake (0/1)]
            Data_Out = [t' omega_proc' brakes'];
            outpath = app.OutputPath.Value;
            if outpath((end-3):end) ~= ".csv"   % if the user did not specify a filename
                outpath = sprintf("%s\\Processed_Profile.csv", outpath);
            end
            writematrix(Data_Out, outpath);
            
            %% First Derivative
            function yout = firstDeriv(yin, xin)
                % initialize
                yout = [];
                % fwd difference approx
                yout(1) = (yin(2) - yin(1))/(xin(2) - xin(1));
                % central difference approx
                for n = 2:(length(yin) - 1)
                    yout(n) = (yin(n+1) - yin(n-1))/(xin(n+1) - xin(n-1));
                end
                % backward difference approx
                yout = [yout (yin(end) - yin(end-1))/(xin(end) - xin(end-1))];
            end
            
            %% Second Derivative
            function yout = secondDeriv(yin, xin)
                % initialize
                yout = [];
                % second order forward difference
                yout(1) = (yin(3) - 2*yin(2) + yin(1))/(xin(2)-xin(1))^2;
                % second order central difference
                for n = 2:(length(yin)-1)
                    yout(n) = (yin(n+1) - 2*yin(n) + yin(n-1))/(xin(n+1)-xin(n))^2;
                end
                % second order backward difference
                yout = [yout (yin(end) - 2*yin(end-1) + yin(end-2))/(xin(end)-xin(end-1))^2];
            end
            
            %% Root Mean Square Error
            function err = rmse(true, processed)
                % initialize square error
                se = [];
                % callculate square errors
                for n = 1:length(true)
                    se(n) = (true(n) - processed(n))^2;
                end
                % calculate root mean
                err = sqrt(sum(se)/length(true));
            end
        end

        % Value changed function: Smoothing
        function autoUpdate(app, event)
            if app.Auto.Value == "On"
                generateProfile(app, event)
            end
        end

        % Value changed function: Mass
        function cwCalc(app, event)
            % system parameters
            d_a = 0.338;    % (m) arm c.o.m. location
            m_a = 18.293;   % (kg) arm mass
            d_e = 1.5;      % (m) experiment c.o.m. location
            m_em = 2.065;   % (kg) mount assembly mass
            % counterweight plate parameters
            t_p = 0.0127;   % (m) counterweight plate thickness
            d_con = 0.0254;	% (m) configuration offset distance
            m_10 = 4.538;   % (kg) 10lb plate mass
            d_10 = 0.7068;  % (m) plate c.o.m.
            m_5 = 2.267;    % (kg) 5lb plate mass
            % counterweight assembly parameters
            m_sf = 0.995;   % (kg) shaft and fasteners mass
            d_sf = 0.5575;  % (m) shaft and fasteners c.o.m.
            m_bp = 0.497;   % (kg) backing plate mass
            d_bp = 0.7227;  % (m) backing plate c.o.m.
            m_pb = 0.5;     % (kg) pin bar mass
            d_pb = 0.3924;  % (m) pin bar c.o.m.
            m_p = 0.1059;   % (kg) pin mass
            d_p = 0.4004;   % (m) pin c.o.m.
            % configuration parameters
            load_nums = 0:0.5:16;
            max_num = 8:2:16;
            conf = 0:4;

            %% User Input
            % experiment mass
            m_ex = app.Mass.Value;  % (kg) experiment mass
            m_e = m_em + m_ex;      % (kg) total experiment assembly mass
            
            %% Exact Reaction Moment
            L_a = d_a*m_a;  % arm moment
            L_e = d_e*m_e;  % experiment moment
            L = L_a + L_e;  % required reaction moment
            
            
            %% Select Lowest Possible Configuration
            % determine max reaction moments
            max_rxns = zeros(1,length(conf));
            for j = 1:length(conf)
                max_rxns(j) = getRXN(conf(j), max_num(j));
            end
            % find smallest config
            configuration = -1;
            for j = 1:length(max_rxns)
                if L < max_rxns(j)
                    configuration = conf(j);
                    break
                end
            end

            %% Select Closest Load Number
            % get all reactions for thie configuration
            deltas = zeros(1,1+2*max_num(configuration+1));
            for j = 1:(length(deltas))
                deltas(j) = abs(L - getRXN(configuration, load_nums(j)));
            end
            % select load number that produces closest reaction
            load_number = load_nums(deltas == min(deltas));
            
            %% Output
            app.Config.Text = sprintf("Configuration: %d", configuration);
            app.LoadNum.Text = sprintf("Load Number: %1.1f", load_number);
            
            %% Get Mass
            function mass = getMass(load_num)
                % calculate mass
                tens = floor(load_num)*m_10;
                fives = 2*(load_num - floor(load_num))*m_5;
                mass = m_sf + m_bp + m_pb + m_p + tens + fives;
            end
            
            %% Get Center of Mass
            function com = getCOM(config, load_num)
                % non-plate center of mass numerator
                sf_num = m_sf*(d_sf + config*d_con);
                bp_num = m_bp*(d_bp + config*d_con);
                pb_num = m_pb*(d_pb + config*d_con);
                p_num = m_p*(d_p + config*d_con);
                np_num = sf_num + bp_num + pb_num + p_num;
                % 10 plate center of mass numerator
                n_10 = floor(load_num);
                tens_mass = n_10*m_10;
                tens_pos = 0;
                if n_10 ~= 0
                    positions = zeros(1,n_10);
                    for i = 1:n_10
                        positions(i) = d_10 + config*d_con - (i-1)*t_p;
                    end
                    tens_pos = sum(positions)/n_10;
                end
                tens_num = tens_mass*tens_pos;
                % 5 plate center of mass numerator
                fives_num = 0;
                if load_num ~= floor(load_num)
                    fives_pos = d_10 + config*d_con - n_10*t_p;
                    fives_num = m_5*fives_pos;
                end
                % total system center of mass
                com = (np_num + tens_num + fives_num)/getMass(load_num);
            end

            %% Get Reaction Moment
            function rxn_mom = getRXN(config, load_num)
                % get mass and c.o.m.
                mass = getMass(load_num);
                com = getCOM(config, load_num);
                % calculate reaction moment
                rxn_mom = mass*com;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {735, 735, 735};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {735, 735};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {225, '1x', 253};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputProfileCSVFilepathLabel = uilabel(app.LeftPanel);
            app.InputProfileCSVFilepathLabel.Text = 'Input Profile .CSV Filepath';
            app.InputPath = uieditfield(app.LeftPanel, 'text');
            app.InputPath.Value = 'D:\1 School\2 Projects\Blast\Matlab_Preprocessing\Data.csv';
            app.OutputProfileCSVFileLocationLabel = uilabel(app.LeftPanel);
            app.OutputProfileCSVFileLocationLabel.Text = 'Output Profile .CSV File Location';
            app.OutputPath = uieditfield(app.LeftPanel, 'text');
            app.OutputPath.Value = 'D:\1 School\2 Projects\Blast\Matlab_Preprocessing';
            app.SmoothingFactorSliderLabel = uilabel(app.LeftPanel);
            app.SmoothingFactorSliderLabel.Text = 'Smoothing Factor';
            app.Smoothing = uislider(app.LeftPanel);
            app.Smoothing.Limits = [0 1];
            app.Smoothing.ValueChangedFcn = createCallbackFcn(app, @autoUpdate, true);
            app.Generate = uibutton(app.LeftPanel, 'push');
            app.Generate.ButtonPushedFcn = createCallbackFcn(app, @generateProfile, true);
            app.Generate.Text = 'Generate Profile';
            app.AutoUpdateLabel = uilabel(app.LeftPanel);
            app.AutoUpdateLabel.Text = 'Auto-Update';
            app.Auto = uiswitch(app.LeftPanel, 'slider');
            app.ProfileGenerationLabel = uilabel(app.LeftPanel);
            app.ProfileGenerationLabel.Text = 'Profile Generation';
            app.CounterweightCalculationLabel = uilabel(app.LeftPanel);
            app.CounterweightCalculationLabel.Text = 'Counterweight Calculation';
            app.kgsLabel = uilabel(app.LeftPanel);
            app.kgsLabel.Text = 'kgs';
            app.PayloadMassSpinnerLabel = uilabel(app.LeftPanel);
            app.PayloadMassSpinnerLabel.Text = {'Payload Mass'; ''};
            app.Mass = uispinner(app.LeftPanel);
            app.Mass.Step = 0.01;
            app.Mass.Limits = [0 29.24];
            app.Mass.ValueChangedFcn = createCallbackFcn(app, @cwCalc, true);
            app.LoadNum = uilabel(app.LeftPanel);
            app.LoadNum.Text = 'Load Number: 0.0';
            app.Config = uilabel(app.LeftPanel);
            app.Config.Text = 'Configuration: 0';
            app.CenterPanel = uipanel(app.GridLayout);
            app.AccelAx = uiaxes(app.CenterPanel);
            title(app.AccelAx, 'Linear Acceleration')
            xlabel(app.AccelAx, 'Time (s)')
            ylabel(app.AccelAx, 'Acceleration (g''s)')
            app.SpeedAx = uiaxes(app.CenterPanel);
            title(app.SpeedAx, 'Angular Speed')
            xlabel(app.SpeedAx, 'Time (s)')
            ylabel(app.SpeedAx, '\omega (rad/s)')
            app.LJerkAx = uiaxes(app.CenterPanel);
            title(app.LJerkAx, 'Linear Jerk')
            xlabel(app.LJerkAx, 'Time (s)')
            ylabel(app.LJerkAx, 'Jerk (m/s^3)')
            app.AJerkAx = uiaxes(app.CenterPanel);
            title(app.AJerkAx, 'Angular Jerk')
            xlabel(app.AJerkAx, 'Time (s)')
            ylabel(app.AJerkAx, 'Jerk (rad/s^3)')
            app.RightPanel = uipanel(app.GridLayout);
            app.AccelRMSE = uilabel(app.RightPanel);
            app.AccelRMSE.Text = 'Linear Accel RMSE = 0.0000';
            app.ProfleRootMeanSquareErrorsLabel = uilabel(app.RightPanel);
            app.ProfleRootMeanSquareErrorsLabel.Text = 'Profle Root Mean Square Errors';
            app.CFunctionCallsTBDLabel = uilabel(app.RightPanel);
            app.CFunctionCallsTBDLabel.Text = 'C# Function Calls (TBD)';
            app.ClipAccelRMSE = uilabel(app.RightPanel);
            app.ClipAccelRMSE.Text = 'Clipped Lin. Acc. RMSE = 0.0000';
            app.SpeedRMSE = uilabel(app.RightPanel);
            app.SpeedRMSE.Text = 'Angular Speed RMSE = 0.0000';
            app.LinJerkRMSE = uilabel(app.RightPanel);
            app.LinJerkRMSE.Text = 'Linear Jerk RMSE = 0.0000';
            app.ClipLinJerkRMSE = uilabel(app.RightPanel);
            app.ClipLinJerkRMSE.Text = 'Clipped Lin. Jerk RMSE = 0.0000';
            app.AngJerkRMSE = uilabel(app.RightPanel);
            app.AngJerkRMSE.Text = 'Angular Jerk RMSE = 0.0000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLAST_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end